classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EstadodelaimagenLabel           matlab.ui.control.Label
        AyudaButton                     matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        HistorialButton                 matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        InicioButton                    matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        ExportarButton                  matlab.ui.control.Button
        RehacerButton                   matlab.ui.control.Button
        DeshacerButton                  matlab.ui.control.Button
        ImportarButton                  matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        AdicindeColorButton             matlab.ui.control.Button
        DegradacinButton                matlab.ui.control.Button
        ReconstruccinButton             matlab.ui.control.Button
        ManchasButton                   matlab.ui.control.Button
        ArrugasPlieguesButton           matlab.ui.control.Button
        RayonesButton                   matlab.ui.control.Button
        HerramientasdeRestauracinLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ImagenRestauradaLabel           matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        ImagenOriginal  % Almacena la imagen importada
        ImagenProcesada % Almacena el resultado tras aplicar filtros/módulos

        % --- Pilas para Deshacer y Rehacer ---
        HistorialImagenes = {} % Celda para almacenar los estados de la imagen
        HistorialNombres = {}  % <--- AGREGAR ESTA LÍNEA
        IndiceHistorial = 0    % Posición actual en el historial
    end
    
    methods (Access = private)
           % Guarda el estado actual en el historial
        function guardarEstado(app, nuevaImagen, nombreOperacion)
            % Si no se especifica el nombre de la operación, usar uno por defecto
            if nargin < 3 || isempty(nombreOperacion)
                nombreOperacion = sprintf('Operación %d', app.IndiceHistorial + 1);
            end

            % Elimina estados de rehacer si nos encontramos en medio del historial
            if app.IndiceHistorial < length(app.HistorialImagenes)
                app.HistorialImagenes = app.HistorialImagenes(1:app.IndiceHistorial);
                app.HistorialNombres = app.HistorialNombres(1:app.IndiceHistorial);
            end
            
            % Agregar el nuevo estado y actualizar el índice
            app.IndiceHistorial = app.IndiceHistorial + 1;
            app.HistorialImagenes{app.IndiceHistorial} = nuevaImagen;
            app.HistorialNombres{app.IndiceHistorial} = nombreOperacion;
            app.ImagenProcesada = nuevaImagen;
        end
        
        % Actualiza los visores UIAxes_2 y UIAxes_4
        function actualizarInterfazProcesada(app)
            img = app.ImagenProcesada;
            
            % 1. Renderizar Imagen Procesada
            imshow(img, 'Parent', app.UIAxes_2);
            
            % 2. Renderizar Histograma Restaurado en UIAxes_4
            if size(img, 3) == 3
                grisHist = rgb2gray(img);
            else
                grisHist = img;
            end
            
            [counts, binLocations] = imhist(grisHist);
            
            cla(app.UIAxes_4);
            bar(app.UIAxes_4, binLocations, counts, 'BarWidth', 1, 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
            app.UIAxes_4.XLim = [0 255];
            app.UIAxes_4.YLimMode = 'auto';
            app.UIAxes_4.YColor = [0.9 0.9 0.9];
            app.UIAxes_4.XColor = [0.9 0.9 0.9];
            
            % 3. Garantizar Histograma Original en UIAxes_3
            if ~isempty(app.ImagenOriginal)
                if size(app.ImagenOriginal, 3) == 3
                    grisOrig = rgb2gray(app.ImagenOriginal);
                else
                    grisOrig = app.ImagenOriginal;
                end
                [countsOrig, binLocOrig] = imhist(grisOrig);
                cla(app.UIAxes_3);
                bar(app.UIAxes_3, binLocOrig, countsOrig, 'BarWidth', 1, 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
                app.UIAxes_3.XLim = [0 255];
                app.UIAxes_3.YLimMode = 'auto';
                app.UIAxes_3.YColor = [0.9 0.9 0.9];
                app.UIAxes_3.XColor = [0.9 0.9 0.9];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            fileFilters = {
                '*.png;*.jpg;*.jpeg;*.tif;*.tiff;*.bmp', 'Archivos de imagen (*.png, *.jpg, *.jpeg, *.tif, *.bmp)'; ...
                '*.png',  'Imágenes PNG (*.png)'; ...
                '*.jpg;*.jpeg', 'Imágenes JPEG (*.jpg, *.jpeg)'; ...
                '*.tif;*.tiff', 'Imágenes TIFF (*.tif, *.tiff)'; ...
                '*.bmp',  'Imágenes Bitmap (*.bmp)'; ...
                '*.*',    'Todos los archivos (*.*)'};
            [fileName, folderPath] = uigetfile(fileFilters, 'Seleccionar imagen antigua');
            if isequal(fileName, 0) || isequal(folderPath, 0)
                return; % El usuario canceló la selección
            end
            try
                fullImagePath = fullfile(folderPath, fileName);
                img = imread(fullImagePath);
    
                app.ImagenOriginal = img;
                app.ImagenProcesada = img;
    
                % Resetear historial
                app.HistorialImagenes = {img};
                app.HistorialNombres = {'Imagen original'};
                app.IndiceHistorial = 1;
    
                imshow(img, 'Parent', app.UIAxes);
                
                % Dibujar Histograma Original en UIAxes_3
                if size(img, 3) == 3
                    grisOrig = rgb2gray(img);
                else
                    grisOrig = img;
                end
                [countsOrig, binLocOrig] = imhist(grisOrig);
                cla(app.UIAxes_3);
                bar(app.UIAxes_3, binLocOrig, countsOrig, 'BarWidth', 1, 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
                app.UIAxes_3.XLim = [0 255];
                app.UIAxes_3.YLimMode = 'auto';
                app.UIAxes_3.YColor = [0.9 0.9 0.9];
                app.UIAxes_3.XColor = [0.9 0.9 0.9];
    
                % Actualizar visor del procesado con la misma imagen inicial
                app.actualizarInterfazProcesada();
    
                app.EstadodelaimagenLabel.Text = sprintf('Estado: Importación exitosa (%s)', fileName);
            catch ME
                app.EstadodelaimagenLabel.Text = 'Estado: Fallo en la importación de la imagen.';
            end
        end

        % Button pushed function: RayonesButton
        function RayonesButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'Por favor, importe una imagen primero.', 'Falta Imagen');
                return;
            end
            
            app.EstadodelaimagenLabel.Text = 'Estado: Eliminando rayones...';
            drawnow;
            
            % 1. Obtener imagen actual
            img = app.ImagenProcesada;
            
            % Convertir a escala de grises para la máscara
            if size(img, 3) == 3
                grayImg = rgb2gray(img);
            else
                grayImg = img;
            end
            
            % 2. Top-Hat morfológico con un elemento estructurante más amplio (para rayones gruesos)
            se = strel('disk', 6); % Aumentado de 4 a 6
            tophatImg = imtophat(grayImg, se);
            
            % Umbral adaptativo basado en la mediana y desviación estándar del Top-Hat
            umbral = median(tophatImg(:)) + 1.8 * std(double(tophatImg(:)));
            mascaraRayones = tophatImg > umbral;
            
            % Engrosar la máscara para cubrir bordes desgastados del rayón
            mascaraRayones = imdilate(mascaraRayones, strel('disk', 2));
            
            % 3. Aplicar Inpainting (regionfill) sobre la máscara generada
            if size(img, 3) == 3
                imgRestaurada = img;
                for c = 1:3
                    imgRestaurada(:,:,c) = regionfill(img(:,:,c), mascaraRayones);
                end
            else
                imgRestaurada = regionfill(img, mascaraRayones);
            end
            
            % 4. Actualizar variable en proceso
            app.ImagenProcesada = imgRestaurada;
            
            % 5. Mostrar imagen procesada
            imshow(imgRestaurada, 'Parent', app.UIAxes_2); 
            
            % 6. Renderizar Histograma Restaurado en UIAxes_4
            if size(imgRestaurada, 3) == 3
                grisHist = rgb2gray(imgRestaurada);
            else
                grisHist = imgRestaurada;
            end
            
            [counts, binLocations] = imhist(grisHist);
            bar(app.UIAxes_4, binLocations, counts, 'BarWidth', 1, 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'none');
            app.UIAxes_4.XLim = [0 255];
            app.UIAxes_4.YColor = [0.9 0.9 0.9];
            app.UIAxes_4.XColor = [0.9 0.9 0.9];
            
            % 7. Renderizar Histograma Original en UIAxes_3 si aún está vacío
            if size(app.ImagenOriginal, 3) == 3
                grisOrig = rgb2gray(app.ImagenOriginal);
            else
                grisOrig = app.ImagenOriginal;
            end
            [countsOrig, binLocOrig] = imhist(grisOrig);
            bar(app.UIAxes_3, binLocOrig, countsOrig, 'BarWidth', 1, 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'none');
            app.UIAxes_3.XLim = [0 255];
            app.UIAxes_3.YColor = [0.9 0.9 0.9];
            app.UIAxes_3.XColor = [0.9 0.9 0.9];

            app.guardarEstado(imgRestaurada);
            app.actualizarInterfazProcesada();
            app.EstadodelaimagenLabel.Text = 'Estado: Rayones eliminados correctamente.';
        end

        % Button pushed function: ArrugasPlieguesButton
        function ArrugasPlieguesButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, ...
                    'Por favor, importe una imagen primero.', ...
                    'Falta Imagen');
                return;
            end

            app.EstadodelaimagenLabel.Text = ...
                'Estado: Atenuando arrugas y pliegues (M2)...';
            drawnow;

            try
                % 2. Obtener la imagen actual
                img = app.ImagenProcesada;

                % 3. Definir el elemento estructurante
                se = strel('disk', 3);

                % 4. Aplicar cierre morfológico y filtro de mediana
                if size(img, 3) == 3

                    % Imagen a color: procesar cada canal RGB
                    imgRestaurada = img;

                    for c = 1:3

                        canal = img(:,:,c);

                        % Cierre morfológico
                        canal = imclose(canal, se);

                        % Filtro de mediana
                        canal = medfilt2(canal, [5 5], 'symmetric');

                        imgRestaurada(:,:,c) = canal;

                    end

                else

                    % Imagen en escala de grises
                    imgRestaurada = imclose(img, se);

                    imgRestaurada = medfilt2( ...
                        imgRestaurada, [5 5], 'symmetric');

                end

                % 5. Guardar el resultado en el historial
                app.guardarEstado(imgRestaurada, ...
                    'M2 - Arrugas y pliegues');

                % 6. Actualizar imagen e histogramas
                app.actualizarInterfazProcesada();

                % 7. Actualizar estado
                app.EstadodelaimagenLabel.Text = ...
                    'Estado: Arrugas y pliegues procesados correctamente.';

            catch ME

                uialert(app.UIFigure, ME.message, ...
                    'Error en M2');

                app.EstadodelaimagenLabel.Text = ...
                    'Estado: Error al procesar arrugas y pliegues.';

            end
        end

        % Button pushed function: DegradacinButton
        function DegradacinButtonPushed(app, event)

           if isempty(app.ImagenOriginal) && isempty(app.ImagenProcesada)
            uialert(app.UIFigure, 'Por favor, importe una imagen primero.', 'Falta Imagen');
            return;
           end
        
            app.EstadodelaimagenLabel.Text = 'Estado: Restaurando imagen degradada por el sol...';
            drawnow;
        
            try
                if ~isempty(app.ImagenProcesada)
                    imgBase = app.ImagenProcesada;
                else
                    imgBase = app.ImagenOriginal;
                end
        
                % 1. Restauración HSV / CLAHE
                if size(imgBase, 3) == 3
                    hsvImg = rgb2hsv(imgBase);
                    hsvImg(:,:,2) = min(hsvImg(:,:,2) * 1.3, 1.0); 
                    hsvImg(:,:,3) = adapthisteq(hsvImg(:,:,3), 'ClipLimit', 0.02);
                    imgRestaurada = hsv2rgb(hsvImg);
                else
                    imgRestaurada = adapthisteq(imgBase, 'ClipLimit', 0.02);
                end
        
                % Filtro gaussiano suave
                imgRestaurada = imgaussfilt(imgRestaurada, 0.6);
                imgRestaurada = im2uint8(imgRestaurada);
        
                % 2. Actualizar propiedad en App Designer
                app.ImagenProcesada = imgRestaurada;
        
                % 3. Mostrar la imagen procesada
                imshow(imgRestaurada, 'Parent', app.UIAxes_2); 
        
                % 4. Renderizar Histograma Restaurado en UIAxes_4
                if size(imgRestaurada, 3) == 3
                    grisHist = rgb2gray(imgRestaurada);
                else
                    grisHist = imgRestaurada;
                end
                
                [counts, binLocations] = imhist(grisHist);
                bar(app.UIAxes_4, binLocations, counts, 'BarWidth', 1, 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
                app.UIAxes_4.XLim = [0 255];
                app.UIAxes_4.YColor = [0.9 0.9 0.9];
                app.UIAxes_4.XColor = [0.9 0.9 0.9];
                
                % 5. Renderizar Histograma Original en UIAxes_3 si aplica
                if size(app.ImagenOriginal, 3) == 3
                    grisOrig = rgb2gray(app.ImagenOriginal);
                else
                    grisOrig = app.ImagenOriginal;
                end
                [countsOrig, binLocOrig] = imhist(grisOrig);
                bar(app.UIAxes_3, binLocOrig, countsOrig, 'BarWidth', 1, 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
                app.UIAxes_3.XLim = [0 255];
                app.UIAxes_3.YColor = [0.9 0.9 0.9];
                app.UIAxes_3.XColor = [0.9 0.9 0.9];
                
                app.guardarEstado(imgRestaurada);
                app.actualizarInterfazProcesada();
                app.EstadodelaimagenLabel.Text = 'Estado: Restauración por degradación solar completada.';
        
            catch ME
                app.EstadodelaimagenLabel.Text = 'Estado: Error al aplicar la restauración por degradación.';
            end
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)
            if app.IndiceHistorial > 1
                app.IndiceHistorial = app.IndiceHistorial - 1;
                app.ImagenProcesada = app.HistorialImagenes{app.IndiceHistorial};

                app.actualizarInterfazProcesada();

                app.EstadodelaimagenLabel.Text = sprintf('Estado: Operación deshecha (%d/%d).', ...
                    app.IndiceHistorial, length(app.HistorialImagenes));
            else
                uialert(app.UIFigure, 'No hay más operaciones para deshacer.', 'Historial Vacío');
            end 
        end

        % Button pushed function: RehacerButton
        function RehacerButtonPushed(app, event)

                if app.IndiceHistorial < length(app.HistorialImagenes)
                    % Avanzar un estado en el historial
                    app.IndiceHistorial = app.IndiceHistorial + 1;
                    app.ImagenProcesada = app.HistorialImagenes{app.IndiceHistorial};

                    % Actualizar la imagen y el histograma UIAxes_4
                    app.actualizarInterfazProcesada();

                    app.EstadodelaimagenLabel.Text = sprintf('Estado: Operación rehecha (%d/%d).', ...
                        app.IndiceHistorial, length(app.HistorialImagenes));
                else
                    uialert(app.UIFigure, 'No hay más operaciones para rehacer.', 'Fin del Historial');
                end
        end

        % Button pushed function: ReconstruccinButton
        function ReconstruccinButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'Por favor, importe una imagen primero.', 'Falta Imagen');
                return;
            end

            app.EstadodelaimagenLabel.Text = 'Estado: Reconstruyendo roturas y esquinas (M4)...';
            drawnow;

            try
                img = app.ImagenProcesada;

                if size(img, 3) == 3
                    grayImg = rgb2gray(img);
                else
                    grayImg = img;
                end

                % 1. Detección de fondo/papel faltante (zonas sobreexpuestas por escaneo > 225)
                mascaraVacios = (grayImg > 225);

                % 2. Limitar la reconstrucción de esquinas estrictamente al PERÍMETRO (25% exterior)
                % Esto aísla el centro de la imagen y protege ojos, nariz y boca
                [filas, columnas] = size(grayImg);
                mascaraPerimetro = false(filas, columnas);
                mF = round(filas * 0.25);
                mC = round(columnas * 0.25);
                mascaraPerimetro(1:mF, :) = true;
                mascaraPerimetro(end-mF+1:end, :) = true;
                mascaraPerimetro(:, 1:mC) = true;
                mascaraPerimetro(:, end-mC+1:end) = true;

                mascaraEsquinas = mascaraVacios & mascaraPerimetro;

                % 3. Detección de grietas morfológicas usando solo Top-Hat con alto umbral
                % Se remueve Bottom-Hat para evitar interpretar sombras oscuras del rostro como daños
                topHat = imtophat(grayImg, strel('disk', 4));
                umbralTopHat = median(topHat(:)) + 3.0 * std(double(topHat(:)));
                mascaraGrietas = topHat > umbralTopHat;

                % 4. Unir máscaras
                mascaraRoturas = mascaraEsquinas | mascaraGrietas;

                % 5. Limpieza y dilatación controlada
                mascaraRoturas = bwareaopen(mascaraRoturas, 30);
                mascaraRoturas = imdilate(mascaraRoturas, strel('disk', 3));

                if ~any(mascaraRoturas(:))
                    app.EstadodelaimagenLabel.Text = 'Estado: No se detectaron roturas en la imagen.';
                    return;
                end

                % 6. Inpainting canal por canal
                if size(img, 3) == 3
                    imgRestaurada = img;
                    for c = 1:3
                        imgRestaurada(:,:,c) = regionfill(img(:,:,c), mascaraRoturas);
                    end
                else
                    imgRestaurada = regionfill(img, mascaraRoturas);
                end

                app.guardarEstado(imgRestaurada);
                app.actualizarInterfazProcesada();
                app.EstadodelaimagenLabel.Text = 'Estado: Reconstrucción M4 completada exitosamente.';

            catch ME
                uialert(app.UIFigure, ME.message, 'Error en Reconstrucción M4');
                app.EstadodelaimagenLabel.Text = 'Estado: Error al ejecutar el módulo M4.';
            end
        end

        % Button pushed function: AdicindeColorButton
        function AdicindeColorButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'Por favor, importe una imagen primero.', 'Falta Imagen');
                return;
            end

            app.EstadodelaimagenLabel.Text = 'Estado: Colorizando por transferencia de luminancia L*a*b* (M6)...';
            drawnow;

            try
                img = app.ImagenProcesada;

                % 1. Extraer escala de grises / luminancia
                if size(img, 3) == 3
                    grayImg = rgb2gray(img);
                else
                    grayImg = img;
                end

                lum = im2double(grayImg);
                L = lum * 100; % Escala L* de 0 a 100

                % 2. Puntos de control para la curva de transferencia cromática (PDI determinista)
                % L_puntos: Niveles de brillo (0=Negro absoluto, 100=Blanco puro)
                % a_puntos: Verde (-) a Rojo (+)
                % b_puntos: Azul (-) a Amarillo (+)
                L_puntos = [0,  12,  30,  55,  75,  92, 100];
                a_puntos = [1,   2,   7,  13,   9,   2,   0];
                b_puntos = [2,   3,  10,  16,  11,   3,   1];

                % 3. Interpolación suave continua (pchip) para cada píxel de la matriz
                % Aplica el tono a TODAS las zonas según su brillo, sin máscaras circulares rígidas
                a = interp1(L_puntos, a_puntos, L, 'pchip');
                b = interp1(L_puntos, b_puntos, L, 'pchip');

                % 4. Filtrado suave morfológico para homogeneizar la transición cromática
                a = imgaussfilt(a, 1.5);
                b = imgaussfilt(b, 1.5);

                % 5. Reconstrucción de L*a*b* a RGB
                imgLAB = cat(3, L, a, b);
                imgRestaurada = lab2rgb(imgLAB);
                imgRestaurada = im2uint8(imgRestaurada);

                % 6. Actualizar historial e interfaz
                app.guardarEstado(imgRestaurada);
                app.actualizarInterfazProcesada();
                app.EstadodelaimagenLabel.Text = 'Estado: Colorización M6 completada exitosamente.';

            catch ME
                uialert(app.UIFigure, ME.message, 'Error en Colorización M6');
                app.EstadodelaimagenLabel.Text = 'Estado: Error al ejecutar la colorización M6.';
            end
        end

        % Button pushed function: ManchasButton
        function ManchasButtonPushed(app, event)
if isempty(app.ImagenProcesada)
        uialert(app.UIFigure, 'Por favor, importe una imagen primero.', 'Falta Imagen');
        return;
    end

    app.EstadodelaimagenLabel.Text = 'Estado: Disminuyendo manchas (M3)...';
    drawnow;

    try
        % --- Parámetros ajustables (calibrados para gotas en el lente) ---
        kFuerte    = 3.0;    % sigmas para semillas (más bajo = más sensible)
        kDebil     = 1.8;    % sigmas para extender la mancha (histéresis)
        factorEsc  = 0.25;   % escala para estimar el fondo (rápido)
        ventanaEsc = 25;     % impar; a escala 0.25 equivale a ~100 px reales
        areaMin    = 30;     % px mínimos para considerar mancha
        areaMaxFr  = 0.010;  % fracción máxima de la imagen por región
        ratioMax   = 3;      % eje mayor/menor (mayor = línea/rayo, se protege)
        solidezMin = 0.5;    % formas muy irregulares se protegen
        radioDilat = 5;      % cubre el halo difuso de la gota
        radioInp   = 15;     % radio de inpaintCoherent
        mostrarMascara = false;  % true para depurar la máscara
        % -----------------------------------------------------------------

        % 0. Normalizar clase: elimina la dependencia de uint8 vs double
        img = im2uint8(app.ImagenProcesada);
        esGris = size(img, 3) == 1;
        if esGris
            img = repmat(img, 1, 1, 3);
        end

        % 1. Lab: luminancia + croma
        lab = rgb2lab(img);
        L = lab(:,:,1);  A = lab(:,:,2);  B = lab(:,:,3);

        % 2. Fondo local robusto, estimado a escala reducida
        fondo = @(X) imresize( ...
                     medfilt2(imresize(X, factorEsc, 'bilinear'), ...
                              [ventanaEsc ventanaEsc], 'symmetric'), ...
                     [size(X,1), size(X,2)], 'bilinear');

        bgL = fondo(L);  bgA = fondo(A);  bgB = fondo(B);

        dL = L - bgL;
        dC = hypot(A - bgA, B - bgB);

        % 3. Puntaje normalizado (MAD); solo oscurecimientos en luminancia
        sL = max(1.4826 * median(abs(dL(:) - median(dL(:)))), 1);
        sC = max(1.4826 * median(dC(:)), 1);
        puntaje = max(max(-dL, 0) / sL, dC / sC);

        % 4. Histéresis: semillas fuertes extendidas a toda la mancha
        mascara = imreconstruct(puntaje > kFuerte, puntaje > kDebil);

        % 5. Filtrado por tamaño
        mascara = bwareaopen(mascara, areaMin);
        mascara = mascara & ~bwareaopen(mascara, round(areaMaxFr * numel(mascara)));

        % 6. Filtrado por forma: proteger líneas, rayo, arcoíris y estructuras alargadas
        cc = bwconncomp(mascara);
        if cc.NumObjects > 0
            st = regionprops(cc, 'Solidity', 'MajorAxisLength', 'MinorAxisLength');
            ratio = [st.MajorAxisLength] ./ max([st.MinorAxisLength], 1);
            conservar = ([st.Solidity] > solidezMin) & (ratio < ratioMax);
            mascara = ismember(labelmatrix(cc), find(conservar));
        end

        % 7. Expandir para cubrir halos de la mancha
        mascara = imdilate(mascara, strel('disk', radioDilat));

        nManchas = bwconncomp(mascara).NumObjects;
        if nManchas == 0
            app.EstadodelaimagenLabel.Text = ...
                'Estado: No se detectaron manchas (prueba bajar kFuerte).';
            return;
        end

        % Depuración visual opcional
        if mostrarMascara
            figure; imshow(labeloverlay(img, mascara, 'Transparency', 0.5));
        end

        % 8. Reparación
        try
            imgRestaurada = inpaintCoherent(img, mascara, ...
                'Radius', radioInp, 'SmoothingFactor', 2);   % Requiere R2019a+ o Computer Vision Toolbox
        catch
            imgRestaurada = img;
            for c = 1:3
                imgRestaurada(:,:,c) = regionfill(img(:,:,c), mascara);
            end
        end

        if esGris
            imgRestaurada = imgRestaurada(:,:,1);
        end

        % 9. Guardar y actualizar interfaz
        app.guardarEstado(imgRestaurada);
        app.actualizarInterfazProcesada();
        app.EstadodelaimagenLabel.Text = sprintf( ...
            'Estado: %d manchas reparadas (%.2f%% de la imagen).', ...
            nManchas, 100 * nnz(mascara) / numel(mascara));

    catch ME
        uialert(app.UIFigure, ME.message, 'Error en Módulo 3');
        app.EstadodelaimagenLabel.Text = 'Estado: Error al aplicar la disminución de manchas.';
    end
        end

        % Button pushed function: HistorialButton
        function HistorialButtonPushed(app, event)
            if app.IndiceHistorial == 0
                uialert(app.UIFigure, 'El historial está vacío. Importe y procese una imagen primero.', 'Historial Vacío');
                return;
            end

            % 1. Cerrar ventana previa si está abierta
            figExistente = findobj('Type', 'figure', 'Name', 'Historial de Operaciones');
            if ~isempty(figExistente)
                close(figExistente);
            end

            % 2. Crear ventana limpia (650x540)
            histFig = uifigure('Name', 'Historial de Operaciones', ...
                               'Position', [200, 150, 650, 540], ...
                               'Color', [0.949 0.9098 0.851]);

            numOperaciones = length(app.HistorialImagenes);
            itemsPorPagina = 3;
            totalPaginas = ceil(numOperaciones / itemsPorPagina);
            pagActual = 1;

            % 3. Grid principal (Fila 1: Miniaturas, Fila 2: Barra de Paginación)
            glMain = uigridlayout(histFig, [2, 1]);
            glMain.RowHeight = {'1x', 45};
            glMain.Padding = [10 10 10 10];

            % Container para las 3 imágenes
            panelPagina = uipanel(glMain, 'BackgroundColor', [0.949 0.9098 0.851], ...
                                          'BorderType', 'none');
            panelPagina.Layout.Row = 1;

            % Grid de 3 filas x 2 columnas para mostrar los 3 items grandes
            glItems = uigridlayout(panelPagina, [3, 2]);
            glItems.ColumnWidth = {200, '1x'};
            glItems.RowHeight = {140, 140, 140};
            glItems.Padding = [5 5 5 5];

            % 4. Barra de navegación inferior
            panelNav = uipanel(glMain, 'BackgroundColor', [0.949 0.9098 0.851], ...
                                       'BorderType', 'none');
            panelNav.Layout.Row = 2;

            glNav = uigridlayout(panelNav, [1, 3]);
            glNav.ColumnWidth = {110, '1x', 110};
            glNav.Padding = [0 5 0 5];

            btnPrev = uibutton(glNav, 'push', 'Text', '◄ Anterior', ...
                               'BackgroundColor', [0.0588 0.251 0.4], ...
                               'FontWeight', 'bold', 'FontColor', [0.949 0.9098 0.851]);
            btnPrev.Layout.Column = 1;

            lblPagina = uilabel(glNav, 'HorizontalAlignment', 'center', ...
                                'FontSize', 14, 'FontWeight', 'bold', ...
                                'FontColor', [0.0588 0.251 0.4]);
            lblPagina.Layout.Column = 2;

            btnNext = uibutton(glNav, 'push', 'Text', 'Siguiente ►', ...
                               'BackgroundColor', [0.0588 0.251 0.4], ...
                               'FontWeight', 'bold', 'FontColor', [0.949 0.9098 0.851]);
            btnNext.Layout.Column = 3;

            % Asignar callbacks a los botones de paginación
            btnPrev.ButtonPushedFcn = @(~,~) cambiarPagina(-1);
            btnNext.ButtonPushedFcn = @(~,~) cambiarPagina(1);

            % Renderizar primera página
            renderizarPagina();

            % --- Funciones internas de renderizado y control ---
            function renderizarPagina()
                % Limpiar grid de items
                delete(glItems.Children);
                
                inicioIdx = (pagActual - 1) * itemsPorPagina + 1;
                finIdx = min(inicioIdx + itemsPorPagina - 1, numOperaciones);
                
                filaGrid = 1;
                for i = inicioIdx:finIdx
                    % Miniatura grande
                    ax = uiaxes(glItems);
                    ax.Layout.Row = filaGrid;
                    ax.Layout.Column = 1;
                    ax.XTick = [];
                    ax.YTick = [];
                    ax.Toolbar.Visible = 'off';
                    
                    imshow(app.HistorialImagenes{i}, 'Parent', ax); %#ok<IMSHOW>
                    
                    % Texto descriptivo
                    lbl = uilabel(glItems);
                    lbl.Layout.Row = filaGrid;
                    lbl.Layout.Column = 2;
                    lbl.FontSize = 15;
                    lbl.FontWeight = 'bold';
                    
                    if i <= length(app.HistorialNombres) && ~isempty(app.HistorialNombres{i})
                        nombreOp = app.HistorialNombres{i};
                    else
                        nombreOp = sprintf('Paso %d', i);
                    end
                    
                    if i == app.IndiceHistorial
                        lbl.Text = sprintf('Paso %d: %s ◄ (Estado Actual)', i, nombreOp);
                        lbl.FontColor = [0.0588 0.251 0.4];
                    else
                        lbl.Text = sprintf('Paso %d: %s', i, nombreOp);
                        lbl.FontColor = [0.4 0.4 0.4];
                    end
                    
                    filaGrid = filaGrid + 1;
                end

                % Actualizar controles del footer
                lblPagina.Text = sprintf('Página %d de %d', pagActual, totalPaginas);
                btnPrev.Enable = pagActual > 1;
                btnNext.Enable = pagActual < totalPaginas;
            end

            function cambiarPagina(delta)
                pagActual = pagActual + delta;
                renderizarPagina();
            end
        

        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, ...
                    'Primero debe importar y procesar una imagen.', ...
                    'Sin imagen');
                return;
            end

            filtros = {
                '*.png', 'Imagen PNG (*.png)';
                '*.jpg', 'Imagen JPEG (*.jpg)';
                '*.tif', 'Imagen TIFF (*.tif)';
                '*.bmp', 'Imagen BMP (*.bmp)'
                };

            [fileName, folderPath, filtro] = uiputfile( ...
                filtros, 'Exportar imagen restaurada', ...
                'imagen_restaurada.png');

            if isequal(fileName, 0)
                return;
            end

            try
         
                [~, ~, extension] = fileparts(fileName);

                if isempty(extension)
                    extensiones = {'.png', '.jpg', '.tif', '.bmp'};
                    fileName = [fileName extensiones{filtro}];
                end

                
                img = app.ImagenProcesada;

              
                rutaCompleta = fullfile(folderPath, fileName);
                imwrite(img, rutaCompleta);

           
                app.EstadodelaimagenLabel.Text = ...
                    'Estado: Imagen exportada correctamente.';

                uialert(app.UIFigure, ...
                    sprintf('Imagen guardada correctamente en:\n%s', ...
                    rutaCompleta), ...
                    'Exportación exitosa');

            catch ME
                uialert(app.UIFigure, ME.message, ...
                    'Error al exportar');

                app.EstadodelaimagenLabel.Text = ...
                    'Estado: Error al exportar la imagen.';
            end
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)

            figAyuda = uifigure('Name', 'Manual de Usuario y Ayuda', 'Position', [100, 100, 550, 450]);

            txtAyuda = uitextarea(figAyuda, 'Position', [20, 20, 510, 410], 'Editable', 'off');

            txtAyuda.Value = {
                'SISTEMA DE RESTAURACIÓN DE IMÁGENES - AYUDA'
                ''
                '--- HERRAMIENTAS DE REPARACIÓN ---'
                '• Rayones: Detecta trazos claros mediante realce morfológico y rellena la máscara. Puede confundir bordes finos o texto, así que revisa zonas de alto contraste.'
                '• Arrugas / Pliegues: Aplica cierre morfológico y un filtro de mediana de 5x5 píxeles. Útil para pequeños cambios locales, pero puede borrar grano o detalle fino.'
                '• Manchas: Busca diferencias respecto del fondo local para reparar manchas oscuras o con croma distinto. Protege formas muy alargadas.'
                ''
                '--- HERRAMIENTAS DE RECONSTRUCCIÓN Y APARIENCIA ---'
                '• Reconstrucción: Repara roturas o esquinas rellenando zonas claras limitadas al 25% exterior de cada borde. No reconstruye contenido semántico inexistente.'
                '• Degradación: Ecualiza el contraste y luminancia local para fotos deslavadas o afectadas por el sol, aplicando después un suavizado gaussiano.'
                '• Adición de color: Colorización automática basada en luminosidad con un sesgo cálido hacia rojos y amarillos.'
                ''
                '--- FLUJO Y EXPORTACIÓN ---'
                '• Historial: Cada operación crea un estado temporal en la sesión. Puedes Deshacer o Rehacer pasos.'
                '• Exportar: Permite guardar el estado actual en PNG, JPG, TIFF o BMP. Se recomienda PNG o TIFF para no perder calidad por compresión.'
                ''
                '--- SOLUCIÓN DE PROBLEMAS FRECUENTES ---'
                '• "Falta imagen": No se ha importado una imagen o la carga no terminó. Vuelve a importar.'
                '• "El resultado no cambia": El defecto no coincide con la máscara automática del módulo; prueba otra herramienta.'
                '• "La imagen queda demasiado suave": Los filtros de pliegues o degradación suavizan detalles; deshaz la acción.'
                '• "No se puede guardar": Verifica los permisos de la ruta de destino o intenta con otra extensión.'
                '• "Falla una herramienta (Alerta)": Puede deberse a dependencias faltantes. El programa requiere funciones de procesamiento nativas de MATLAB (como inpaintCoherent o regionfill).'
                };
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Recursos', 'logofondo.png');
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_3 = uiaxes(app.Panel);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.Panel);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Inicio';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Restaura hoy, conserva siempre ';
            app.OriginalLabel = uilabel(app.Panel);
            app.OriginalLabel.Text = 'Original';
            app.ImagenRestauradaLabel = uilabel(app.Panel);
            app.ImagenRestauradaLabel.Text = 'Imagen Restaurada';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Histograma';
            app.Panel_2 = uipanel(app.Panel);
            app.HerramientasdeRestauracinLabel = uilabel(app.Panel_2);
            app.HerramientasdeRestauracinLabel.Text = ' Herramientas de Restauración';
            app.RayonesButton = uibutton(app.Panel_2, 'push');
            app.RayonesButton.ButtonPushedFcn = createCallbackFcn(app, @RayonesButtonPushed, true);
            app.RayonesButton.Text = 'Rayones';
            app.ArrugasPlieguesButton = uibutton(app.Panel_2, 'push');
            app.ArrugasPlieguesButton.ButtonPushedFcn = createCallbackFcn(app, @ArrugasPlieguesButtonPushed, true);
            app.ArrugasPlieguesButton.Text = 'Arrugas / Pliegues';
            app.ManchasButton = uibutton(app.Panel_2, 'push');
            app.ManchasButton.ButtonPushedFcn = createCallbackFcn(app, @ManchasButtonPushed, true);
            app.ManchasButton.Text = 'Manchas';
            app.ReconstruccinButton = uibutton(app.Panel_2, 'push');
            app.ReconstruccinButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstruccinButtonPushed, true);
            app.ReconstruccinButton.Text = 'Reconstrucción';
            app.DegradacinButton = uibutton(app.Panel_2, 'push');
            app.DegradacinButton.ButtonPushedFcn = createCallbackFcn(app, @DegradacinButtonPushed, true);
            app.DegradacinButton.Text = 'Degradación';
            app.AdicindeColorButton = uibutton(app.Panel_2, 'push');
            app.AdicindeColorButton.ButtonPushedFcn = createCallbackFcn(app, @AdicindeColorButtonPushed, true);
            app.AdicindeColorButton.Text = 'Adición de Color';
            app.ImportarButton = uibutton(app.Panel, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Tooltip = {'PNG, JPG/JPEG, TIFF y BMP'};
            app.ImportarButton.Text = 'Importar';
            app.DeshacerButton = uibutton(app.Panel, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Tooltip = {'PNG, JPG/JPEG, TIFF y BMP'};
            app.DeshacerButton.Text = 'Deshacer';
            app.RehacerButton = uibutton(app.Panel, 'push');
            app.RehacerButton.ButtonPushedFcn = createCallbackFcn(app, @RehacerButtonPushed, true);
            app.RehacerButton.Tooltip = {'PNG, JPG/JPEG, TIFF y BMP'};
            app.RehacerButton.Text = 'Rehacer';
            app.ExportarButton = uibutton(app.Panel, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Recursos', '2.png');
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.Text = 'Inicio';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Recursos', '4.png');
            app.HistorialButton = uibutton(app.UIFigure, 'push');
            app.HistorialButton.ButtonPushedFcn = createCallbackFcn(app, @HistorialButtonPushed, true);
            app.HistorialButton.Text = 'Historial';
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Recursos', '3.png');
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.EstadodelaimagenLabel = uilabel(app.UIFigure);
            app.EstadodelaimagenLabel.Text = ' Estado de la imagen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end